classdef PIWPAWAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        SciekadoplikuEditFieldLabel  matlab.ui.control.Label
        SciekadoplikuEditField       matlab.ui.control.EditField
        ObliczobwdButton             matlab.ui.control.Button
        WynikimetodobliczaniaobwoduPanel  matlab.ui.container.Panel
        ZliczaniepikseliLabel        matlab.ui.control.Label
        KodwacuchowychLabel          matlab.ui.control.Label
        ZewntrznychbokwLabel         matlab.ui.control.Label
        wynik1Label                  matlab.ui.control.Label
        wynik2Label                  matlab.ui.control.Label
        wynik3Label                  matlab.ui.control.Label
        CzwrekpikseliLabel           matlab.ui.control.Label
        wynik4Label                  matlab.ui.control.Label
        ProjektPIW2020Label          matlab.ui.control.Label
        autorzyPiotrDreganKrystianKrasuckiLabel  matlab.ui.control.Label
        ObrazwejciowyLabel           matlab.ui.control.Label
        ObrazzkonturemLabel          matlab.ui.control.Label
        UIAxes3                      matlab.ui.control.UIAxes
    end


    properties (Access = public)
        value % Scieżka do pliku
    end

    methods (Access = public)
    
        function wyniki = obliczobwod(app, OBRAZ)
            Image=double(imread([OBRAZ]));
            Image=Image./255;
            Image = padarray(Image, [1 1], 0);
            [x,y,z] = size(Image);
            old_pix = 0;
            Obw1 = 0;
            ImageNew = zeros(x,y);
            for i = 1:x
               for j = 1:y
                   new_pix = Image(i,j);
                   if old_pix == new_pix
                   elseif old_pix > new_pix
                       ImageNew(i,j-1) = 1;
                   elseif old_pix < new_pix
                       ImageNew(i,j) = 1;
                   end
                   old_pix = new_pix;
               end
            end
            for i = 1:y
               for j = 1:x
                   new_pix = Image(j,i);
                   if old_pix == new_pix
                   elseif old_pix > new_pix
                       ImageNew(j-1,i) = 1;
                   elseif old_pix < new_pix
                       ImageNew(j,i) = 1;
                   end
                   old_pix = new_pix;
               end
            end
            %Pierwsza metoda
            for i = 1:x
               for j = 1:y
                    if ImageNew(i,j) == 1
                        Obw1 = Obw1 + 1;
                    end
               end
            end
            %Druga metoda
            sum_p = 0;
            sum_n = 0;
            
            for i=2:x-1
                for j=2:y-1
                    frame = ImageNew(i-1:i+1, j-1:j+1);
                    
                    if frame(2, 2) == 1
            %             sum_p = sum_p + sum(frame([2, 4, 6, 8]));
                        if sum(frame([2, 4, 6, 8])) == 1
                            sum_p = sum_p + 1;
                        elseif (sum(frame([2, 4, 6, 8])) > 1)
                            sum_p = sum_p + 2;
                        end
                        
                        if sum(frame([1, 3, 7, 9])) > 0 && sum(frame([2, 4, 6, 8])) == 1
                            sum_n = sum_n + 1;
                        elseif sum(frame([1, 3, 7, 9])) > 0 && sum(frame([2, 4, 6, 8])) == 0
                            sum_n = sum_n + 2;
                        end
                    end
                end
            end
            Obw2 = (sum_p/2) + (sum_n/2)*sqrt(2);
            %Trzecia metoda
            [idx_x, idx_y] = find(ImageNew);
            PIXall = [idx_x idx_y];
            a = ((pi)*(1 + sqrt(2)))/(8);
            b = (pi)/(8*sqrt(2));
            Wierz = 0;
            Boki = 0;
            for i=1:Obw1
            StartX = PIXall(i,1);
            StartY = PIXall(i,2);
            frame = Image(StartX-1:StartX+1,StartY-1:StartY+1);
            p = sum(frame([2, 4, 6, 8]));
            Wierz = Wierz + (3-p);
            Boki = Boki + (4-p);
            end
            Obw3 = (a*Boki) - (b*Wierz);
            %Czwarta metoda
            [h, w] = size(Image);

            Q{1, 1} = [1 0; 0 0];
            Q{1, 2} = [0 0; 1 0];
            Q{1, 3} = [0 0; 0 1];
            Q{1, 4} = [0 1; 0 0];
        
            Q{2, 1} = [1 0; 1 0];
            Q{2, 2} = [0 0; 1 1];
            Q{2, 3} = [0 1; 0 1];
            Q{2, 4} = [1 1; 0 0];
        
            Q{3, 1} = [1 0; 1 1];
            Q{3, 2} = [0 1; 1 1];
            Q{3, 3} = [1 1; 0 1];
            Q{3, 4} = [1 1; 1 0];
        
            Q{4, 1} = [1 1; 1 1];
        
            Q{5, 1} = [1 0; 0 1];
            Q{5, 2} = [0 1; 1 0];
            
            p = cell(1,5);
            p{1} = 0;
            p{2} = 0;
            p{3} = 0;
            p{4} = 0;
            p{5} = 0;
        
            for i=1:h-1
                for j=1:w-1
                    frame = Image(i:i+1, j:j+1);
        
                    for k=1:size(Q, 1)
                        for m=1:size(Q, 2)
        
                            if numel(Q{k, m}) ~= 0
                                if frame == Q{k, m}
                                    p{k} = p{k} + 1;
                                end
                            end
        
                        end
                    end
        
                end
            end
        
            obw4 = p{2} + (1/sqrt(2))*(p{1}+p{3}+2*p{5});
            %Przypisanie wyników
            wyniki.obw1 = Obw1;
            wyniki.obw2 = Obw2;
            wyniki.obw3 = Obw3;
            wyniki.obw4 = obw4;
            wyniki.kontur = ImageNew;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Image2 = imread('Background.png');
            imshow(Image2,'Parent',app.UIAxes);
            imshow(Image2,'Parent',app.UIAxes2);
            Image = imread('arrow.png');
            Image = imresize(Image, 2.2);
            imshow(Image,'Parent',app.UIAxes3);
            app.wynik1Label.Text = '';
            app.wynik2Label.Text = '';
            app.wynik3Label.Text = '';
            app.wynik4Label.Text = '';
        end

        % Value changing function: SciekadoplikuEditField
        function SciekadoplikuEditFieldValueChanging(app, event)

        end

        % Value changed function: SciekadoplikuEditField
        function SciekadoplikuEditFieldValueChanged(app, event)
            app.value = app.SciekadoplikuEditField.Value;
            if isfile([app.value])
                Image = imread([app.value]);
                Image = padarray(Image, [1 1], 0);
                ImageNew = imresize(Image, 2.2);
                imshow(Image,'Parent',app.UIAxes);
            end
            imshow('Background.png','Parent',app.UIAxes2);
            app.wynik1Label.Text = '';
            app.wynik2Label.Text = '';
            app.wynik3Label.Text = '';
            app.wynik4Label.Text = '';
        end

        % Button pushed function: ObliczobwdButton
        function ObliczobwdButtonPushed(app, event)
            if isfile([app.value])
                WYNIKI = obliczobwod(app, (app.value));
                ImageNew = WYNIKI.kontur;
                ImageNew = imresize(ImageNew, 2.2);
                imshow(ImageNew,'Parent',app.UIAxes2);
                WYNIKI.obw1 = round(double(WYNIKI.obw1), 2);
                WYNIKI.obw2 = round(double(WYNIKI.obw2), 2);
                WYNIKI.obw3 = round(double(WYNIKI.obw3), 2);
                WYNIKI.obw4 = round(double(WYNIKI.obw4), 2);
                Obwod1 = num2str(WYNIKI.obw1);
                Obwod2 = num2str(WYNIKI.obw2);
                Obwod3 = num2str(WYNIKI.obw3);
                Obwod4 = num2str(WYNIKI.obw4);
                app.wynik1Label.Text = Obwod1;
                app.wynik2Label.Text = Obwod2;
                app.wynik3Label.Text = Obwod3;
                app.wynik4Label.Text = Obwod4;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.SciekadoplikuEditFieldLabel = uilabel(app.UIFigure);
            app.SciekadoplikuEditFieldLabel.Text = 'Scieżka do pliku';
            app.SciekadoplikuEditField = uieditfield(app.UIFigure, 'text');
            app.SciekadoplikuEditField.ValueChangedFcn = createCallbackFcn(app, @SciekadoplikuEditFieldValueChanged, true);
            app.SciekadoplikuEditField.ValueChangingFcn = createCallbackFcn(app, @SciekadoplikuEditFieldValueChanging, true);
            app.ObliczobwdButton = uibutton(app.UIFigure, 'push');
            app.ObliczobwdButton.ButtonPushedFcn = createCallbackFcn(app, @ObliczobwdButtonPushed, true);
            app.ObliczobwdButton.Text = 'Oblicz obwód';
            app.WynikimetodobliczaniaobwoduPanel = uipanel(app.UIFigure);
            app.WynikimetodobliczaniaobwoduPanel.Title = 'Wyniki metod obliczania obwodu:';
            app.ZliczaniepikseliLabel = uilabel(app.WynikimetodobliczaniaobwoduPanel);
            app.ZliczaniepikseliLabel.Text = 'Zliczanie pikseli: ';
            app.KodwacuchowychLabel = uilabel(app.WynikimetodobliczaniaobwoduPanel);
            app.KodwacuchowychLabel.Text = 'Kodów łańcuchowych: ';
            app.ZewntrznychbokwLabel = uilabel(app.WynikimetodobliczaniaobwoduPanel);
            app.ZewntrznychbokwLabel.Text = 'Zewnętrznych boków: ';
            app.wynik1Label = uilabel(app.WynikimetodobliczaniaobwoduPanel);
            app.wynik1Label.Text = 'wynik1';
            app.wynik2Label = uilabel(app.WynikimetodobliczaniaobwoduPanel);
            app.wynik2Label.Text = 'wynik2';
            app.wynik3Label = uilabel(app.WynikimetodobliczaniaobwoduPanel);
            app.wynik3Label.Text = 'wynik3';
            app.CzwrekpikseliLabel = uilabel(app.WynikimetodobliczaniaobwoduPanel);
            app.CzwrekpikseliLabel.Text = 'Czwórek pikseli: ';
            app.wynik4Label = uilabel(app.WynikimetodobliczaniaobwoduPanel);
            app.wynik4Label.Text = 'wynik4';
            app.ProjektPIW2020Label = uilabel(app.UIFigure);
            app.ProjektPIW2020Label.Text = 'Projekt PIW 2020';
            app.autorzyPiotrDreganKrystianKrasuckiLabel = uilabel(app.UIFigure);
            app.autorzyPiotrDreganKrystianKrasuckiLabel.Text = 'autorzy : Piotr Dregan, Krystian Krasucki';
            app.ObrazwejciowyLabel = uilabel(app.UIFigure);
            app.ObrazwejciowyLabel.Text = 'Obraz wejściowy';
            app.ObrazzkonturemLabel = uilabel(app.UIFigure);
            app.ObrazzkonturemLabel.Text = 'Obraz z konturem';
            app.UIAxes3 = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = PIWPAWAPP

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end